classdef MouseVideoBehavioralAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExporttoCSVButton               matlab.ui.control.Button
        MouseVideoBehavioralAnalysisLabel  matlab.ui.control.Label
        PickVideoDirectoryButton        matlab.ui.control.Button
        DirectoryChosenLabel            matlab.ui.control.Label
        LengthofVideosinsecondsEditFieldLabel  matlab.ui.control.Label
        LengthofVideos                  matlab.ui.control.NumericEditField
        IntervalinsecondsEditFieldLabel  matlab.ui.control.Label
        IntervalInSec                   matlab.ui.control.NumericEditField
        NameofOutputFileEditFieldLabel  matlab.ui.control.Label
        NameofOutput                    matlab.ui.control.EditField
        Status                          matlab.ui.control.TextArea
        MinimumThresholdForFreezingcmsecEditFieldLabel  matlab.ui.control.Label
        MinimumThresholdForFreezing     matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        PathToDirectory % Description
    end


    methods (Access = private)

        % Button pushed function: ExporttoCSVButton
        function ExportToCSV(app, event)
            directory = dir([app.PathToDirectory '/*.mov']);
            lengthVid = app.LengthofVideos.Value;
            intervalSeconds = app.IntervalInSec.Value;
            output_name = app.NameofOutput.Value;
            minThreshold = app.MinimumThresholdForFreezing.Value;
            
            if ~isempty(minThreshold) && ~isempty(directory) && ~isempty(lengthVid) && ~isempty(intervalSeconds) && ~isempty(output_name)
                for i=0:intervalSeconds:(lengthVid-intervalSeconds)
                    app.Status.Value = [app.Status.Value; strjoin({'Started Interval ' num2str(i) '-' num2str(i+intervalSeconds) 'sec'})];
                    analyze(directory, output_name, [num2str(i) '-' num2str(i+intervalSeconds) ' sec'], i, i+intervalSeconds, minThreshold);
                    app.Status.Value = [app.Status.Value; strjoin({'Finished Interval ' num2str(i) '-' num2str(i+intervalSeconds) ' sec'})];
                end
            end
        end

        % Button pushed function: PickVideoDirectoryButton
        function PickVideoDirectory(app, event)
            path = uigetdir();
            app.PathToDirectory = path;
            app.DirectoryChosenLabel.Text = app.PathToDirectory;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ExporttoCSVButton = uibutton(app.UIFigure, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToCSV, true);
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.MouseVideoBehavioralAnalysisLabel = uilabel(app.UIFigure);
            app.MouseVideoBehavioralAnalysisLabel.Text = 'Mouse Video Behavioral Analysis';
            app.PickVideoDirectoryButton = uibutton(app.UIFigure, 'push');
            app.PickVideoDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PickVideoDirectory, true);
            app.PickVideoDirectoryButton.Text = 'Pick Video Directory';
            app.DirectoryChosenLabel = uilabel(app.UIFigure);
            app.DirectoryChosenLabel.Text = 'Directory Chosen';
            app.LengthofVideosinsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofVideosinsecondsEditFieldLabel.Text = 'Length of Videos (in seconds)';
            app.LengthofVideos = uieditfield(app.UIFigure, 'numeric');
            app.LengthofVideos.Value = 100;
            app.IntervalinsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalinsecondsEditFieldLabel.Text = 'Interval (in seconds)';
            app.IntervalInSec = uieditfield(app.UIFigure, 'numeric');
            app.IntervalInSec.Value = 2;
            app.NameofOutputFileEditFieldLabel = uilabel(app.UIFigure);
            app.NameofOutputFileEditFieldLabel.Text = 'Name of Output File';
            app.NameofOutput = uieditfield(app.UIFigure, 'text');
            app.Status = uitextarea(app.UIFigure);
            app.Status.Editable = 'off';
            app.MinimumThresholdForFreezingcmsecEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumThresholdForFreezingcmsecEditFieldLabel.Text = 'Minimum Threshold For Freezing (cm/sec)';
            app.MinimumThresholdForFreezing = uieditfield(app.UIFigure, 'numeric');
            app.MinimumThresholdForFreezing.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = MouseVideoBehavioralAnalysisGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end